classdef filtraggioAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        dft                            matlab.ui.control.UIAxes
        segnaleF                       matlab.ui.control.UIAxes
        dftF                           matlab.ui.control.UIAxes
        segnale                        matlab.ui.control.UIAxes
        AudioseleziontoDropDownLabel   matlab.ui.control.Label
        AudioseleziontoDropDown        matlab.ui.control.DropDown
        TipologiadifiltraggioDropDownLabel  matlab.ui.control.Label
        TipologiadifiltraggioDropDown  matlab.ui.control.DropDown
        play                           matlab.ui.control.Button
        stop                           matlab.ui.control.Button
        playF                          matlab.ui.control.Button
        stopF                          matlab.ui.control.Button
        FLow                           matlab.ui.control.EditField
        FHigh                          matlab.ui.control.EditField
        leftLabel                      matlab.ui.control.Label
        rightLabel                     matlab.ui.control.Label
        LoadSignalButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        audio % Description
        fs
        audioF
    end
    
    methods (Access = private)
        
        function filtra(app)
            value = app.TipologiadifiltraggioDropDown.Value;
            lf = str2double(app.FLow.Value);
            hf = str2double(app.FHigh.Value);
            f = ([0:size(app.audio)-1]' ./ (size(app.audio)-1) -1/2)*app.fs;
            f = f(:,1);
            if value == "Nessuno"
               filter = 1;
               app.FLow.Visible = false;
               app.FHigh.Visible = false;
               app.leftLabel.Text = "";
               app.rightLabel.Text = "";
            elseif value == "Passa-Basso"
               app.FLow.Visible = true;
               app.FHigh.Visible = false;
               app.leftLabel.Text = "F taglio";
               app.rightLabel.Text = "";
               filter = (abs(f)<=lf);
            elseif value == "Passa-Alto"
                app.FLow.Visible = true;
                app.FHigh.Visible = false;
                filter = (abs(f)>=lf);
                app.leftLabel.Text = "F taglio";
                app.rightLabel.Text = "";
            elseif value == "Passa-Banda"
                app.FLow.Visible = true;
                app.FHigh.Visible = true;
                filter = (abs(f)>=lf).*(abs(f)<=hf);
                app.leftLabel.Text = "F taglio inf";
                app.rightLabel.Text = "F taglio sup";
            elseif value == "Elimina-Banda"
                app.FLow.Visible = true;
                app.FHigh.Visible = true;
                filter = 1 - (abs(f)>=lf).*(abs(f)<=hf);
                app.leftLabel.Text = "F  inferiore";
                app.rightLabel.Text = "F  superiore";
            end
            
            Y = fftshift(fft(app.audio)).*filter;
            cla(app.dftF);
            
            Y_c = Y;
            half = floor(length(Y)/2);
            Y_c = Y_c(half+1:end);
            f = f(half+1:end);
            plot(app.dftF, f, abs(Y_c));
            app.audioF = real(ifft(ifftshift(Y)));
            t = [0:size(app.audio)-1]' ./app.fs;
            cla(app.segnaleF);
            plot(app.segnaleF, t, app.audioF);
        end
        
        function  carica(app)
            t = [0:size(app.audio)-1]' ./app.fs;
            cla(app.segnale);
            plot(app.segnale, t, app.audio);
            
            f = ([0:size(app.audio)-1]' ./ (size(app.audio)-1) -1/2)*app.fs;
            f = f(:,1);
            Y = abs(fftshift(fft(app.audio)));
            
            half = floor(length(Y)/2);
            f = f(half+1:end);
            Y = Y(half+1:end);
            cla(app.dft);
            plot(app.dft, f, Y);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AudioseleziontoDropDown
        function AudioseleziontoDropDownValueChanged(app, event)
            value = app.AudioseleziontoDropDown.Value;
            if value == "Nessuno"
                app.audio = 0;
                app.fs = 0;
            elseif value == "Gong"
                load gong;
                app.audio = y;
                app.fs = Fs;
            elseif value == "Chirp"
                load Chirp;
                app.audio = y;
                app.fs = Fs;
            elseif value == "Treno"
                load train;
                app.audio = y;
                app.fs = Fs;
            elseif value == "Risata"
                load laughter;
                app.audio = y;
                app.fs = Fs;
            end
            
            
            app.carica()
            app.filtra()
        end

        % Button pushed function: play
        function playButtonPushed(app, event)
            sound(app.audio, app.fs);
        end

        % Button pushed function: stop
        function stopButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: playF
        function playFButtonPushed(app, event)
            sound(app.audioF, app.fs);
        end

        % Button pushed function: stopF
        function stopFButtonPushed(app, event)
            clear sound;
        end

        % Value changed function: TipologiadifiltraggioDropDown
        function TipologiadifiltraggioDropDownValueChanged(app, event)
            app.filtra();
        end

        % Callback function
        function FrequenzaditaglioSliderValueChanged(app, event)
           
            
        end

        % Value changed function: FLow
        function FLowValueChanged(app, event)
            app.filtra();
        end

        % Value changed function: FHigh
        function FHighValueChanged(app, event)
            app.filtra();
        end

        % Button pushed function: LoadSignalButton
        function LoadSignalButtonPushed(app, event)
            [path, file] = uigetfile("*.mp3");
            [y,app.fs] = audioread(strcat(file,path));
            y = y(:,1);
            app.audio = y;
            
            app.carica()
            app.filtra()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.dft = uiaxes(app.UIFigure);
            title(app.dft, 'DFT')
            xlabel(app.dft, 'f')
            ylabel(app.dft, '')
            app.segnaleF = uiaxes(app.UIFigure);
            title(app.segnaleF, 'Segnale filtrato')
            xlabel(app.segnaleF, 't')
            ylabel(app.segnaleF, '')
            app.dftF = uiaxes(app.UIFigure);
            title(app.dftF, 'DFT filtrate')
            xlabel(app.dftF, 'f')
            ylabel(app.dftF, '')
            app.segnale = uiaxes(app.UIFigure);
            title(app.segnale, 'Segnale originale')
            xlabel(app.segnale, 't')
            ylabel(app.segnale, '')
            app.AudioseleziontoDropDownLabel = uilabel(app.UIFigure);
            app.AudioseleziontoDropDownLabel.Text = 'Audio selezionto';
            app.AudioseleziontoDropDown = uidropdown(app.UIFigure);
            app.AudioseleziontoDropDown.Items = {'Nessuno', 'Gong', 'Chirp', 'Treno', 'Risata'};
            app.AudioseleziontoDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioseleziontoDropDownValueChanged, true);
            app.AudioseleziontoDropDown.Value = 'Nessuno';
            app.TipologiadifiltraggioDropDownLabel = uilabel(app.UIFigure);
            app.TipologiadifiltraggioDropDownLabel.Text = 'Tipologia di filtraggio';
            app.TipologiadifiltraggioDropDown = uidropdown(app.UIFigure);
            app.TipologiadifiltraggioDropDown.Items = {'Nessuno', 'Passa-Basso', 'Passa-Alto', 'Passa-Banda', 'Elimina-Banda'};
            app.TipologiadifiltraggioDropDown.ValueChangedFcn = createCallbackFcn(app, @TipologiadifiltraggioDropDownValueChanged, true);
            app.TipologiadifiltraggioDropDown.Value = 'Nessuno';
            app.play = uibutton(app.UIFigure, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.play.Text = 'play';
            app.stop = uibutton(app.UIFigure, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stop.Text = 'stop';
            app.playF = uibutton(app.UIFigure, 'push');
            app.playF.ButtonPushedFcn = createCallbackFcn(app, @playFButtonPushed, true);
            app.playF.Text = 'play';
            app.stopF = uibutton(app.UIFigure, 'push');
            app.stopF.ButtonPushedFcn = createCallbackFcn(app, @stopFButtonPushed, true);
            app.stopF.Text = 'stop';
            app.FLow = uieditfield(app.UIFigure, 'text');
            app.FLow.ValueChangedFcn = createCallbackFcn(app, @FLowValueChanged, true);
            app.FLow.Visible = 'off';
            app.FLow.Value = '200';
            app.FHigh = uieditfield(app.UIFigure, 'text');
            app.FHigh.ValueChangedFcn = createCallbackFcn(app, @FHighValueChanged, true);
            app.FHigh.Visible = 'off';
            app.FHigh.Value = '800';
            app.leftLabel = uilabel(app.UIFigure);
            app.leftLabel.Text = '';
            app.rightLabel = uilabel(app.UIFigure);
            app.rightLabel.Text = '';
            app.LoadSignalButton = uibutton(app.UIFigure, 'push');
            app.LoadSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSignalButtonPushed, true);
            app.LoadSignalButton.Text = 'Load Signal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filtraggioAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end