classdef QuadraturaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        AmpiezzaSlider      matlab.ui.control.Slider
        fxLabel             matlab.ui.control.Label
        fxEditField         matlab.ui.control.EditField
        PlotButton          matlab.ui.control.Button
        AEditFieldLabel     matlab.ui.control.Label
        AEditField          matlab.ui.control.EditField
        BEditFieldLabel     matlab.ui.control.Label
        BEditField          matlab.ui.control.EditField
        resLabel            matlab.ui.control.Label
        AreaCalcolataLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        f % Description
        a
        b
        N
        ok = false
    end
    
    methods (Access = private)
        
        function trapezi_cmp(app)
            fun_s = "@(x)(" + app.fxEditField.Value + ")";
            app.a = str2double(app.AEditField.Value);
            app.b = str2double(app.BEditField.Value);
            app.f = str2func(fun_s);
            cla(app.UIAxes);
            hold(app.UIAxes);
            fplot(app.UIAxes, app.f,  [app.a,app.b]);
            
      
            n_points = round( app.AmpiezzaSlider.Value)+1;
            points = linspace(app.a, app.b, n_points);
            app.N = size(points);
            app.N = app.N(2);
            for i = 1:app.N-1
               pgon = polyshape([points(i+1) points(i) points(i) points(i+1)],[0 0 app.f(points(i)) app.f(points(i+1))]);
               plot(app.UIAxes,pgon);
            end
            hold(app.UIAxes); 
            app.resLabel.Text = num2str(app.quadratura_trapezoidale_composita(app.f, app.a, app.b, app.N));
        end
        
        function y = quadratura_trapezoidale_composita(app, f, a, b, N)
            t = linspace(a,b,N+1)'; 
            %divido l'intervallo [a,b] in n sottointervalli di ugual dimensione
            delta = (b-a)./N; %lunghezza di ogni sottointervallo
            M = diag(ones(N+1,1)) + diag(ones(N,1), 1);
            %definisco questa matrice per il calcolo di f(x[k]) + f(x[k+1]) 
            vals = M*f(t); vals = vals(1:N);
            y = sum(vals)*delta/2; 
            %calcolo dell'area come sommatoria(f[xk]+f(x[k+1])/2 * (b-a)/N)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmpiezzaSlider
        function AmpiezzaSliderValueChanged(app, event)
            if(app.ok == true)
               app.trapezi_cmp();
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.ok = true;
            app.trapezi_cmp();
        end

        % Value changing function: AmpiezzaSlider
        function AmpiezzaSliderValueChanging(app, event)
            app.AmpiezzaSlider.Value = event.Value;
            app.trapezi_cmp();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AmpiezzaSlider = uislider(app.UIFigure);
            app.AmpiezzaSlider.Limits = [1 20];
            app.AmpiezzaSlider.MajorTicks = [1 3 5 7 9 11 13 15 17 19];
            app.AmpiezzaSlider.MajorTickLabels = {'1', '3', '5', '7', '9', '11', '13', '15', '17', '19'};
            app.AmpiezzaSlider.ValueChangedFcn = createCallbackFcn(app, @AmpiezzaSliderValueChanged, true);
            app.AmpiezzaSlider.ValueChangingFcn = createCallbackFcn(app, @AmpiezzaSliderValueChanging, true);
            app.AmpiezzaSlider.MinorTicks = [];
            app.AmpiezzaSlider.Value = 1;
            app.fxLabel = uilabel(app.UIFigure);
            app.fxLabel.Text = 'f(x)  = ';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A: ';
            app.AEditField = uieditfield(app.UIFigure, 'text');
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B :';
            app.BEditField = uieditfield(app.UIFigure, 'text');
            app.resLabel = uilabel(app.UIFigure);
            app.resLabel.Text = '';
            app.AreaCalcolataLabel = uilabel(app.UIFigure);
            app.AreaCalcolataLabel.Text = 'Area Calcolata:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuadraturaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end